classdef app1 < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure                        matlab.ui.Figure
		ClosingButton                   matlab.ui.control.Button
		OpeningButton                   matlab.ui.control.Button
		DilatationButton                matlab.ui.control.Button
		ErosionButton                   matlab.ui.control.Button
		SelectSESizeDropDown            matlab.ui.control.DropDown
		SelectSESizeDropDownLabel       matlab.ui.control.Label
		StructuringElementsButtonGroup  matlab.ui.container.ButtonGroup
		DiamondButton                   matlab.ui.control.RadioButton
		DiskButton_2                    matlab.ui.control.RadioButton
		SquareButton_2                  matlab.ui.control.RadioButton
		ImagemorphologyLabel            matlab.ui.control.Label
		BasicoperationsLabel            matlab.ui.control.Label
		CreateahistogramButton          matlab.ui.control.Button
		SaveyourImageButton             matlab.ui.control.Button
		ContrastButton                  matlab.ui.control.Button
		UploadYourImageButton           matlab.ui.control.Button
		BlackAndWhiteButton             matlab.ui.control.Button
		GreyscaleButton                 matlab.ui.control.Button
		UIAxes                          matlab.ui.control.UIAxes
		ImageAxes                       matlab.ui.control.UIAxes
	end


	properties (Access = private)
		im;
		diskse;
		diamondse;
		squarese;
		sesize;
		I_erosion;
		I_dilate;
		I_open;
		I_close;
	end

	methods (Access = private)

		function updateimage(app,imagefile)
			try
				app.im=imread(imagefile);
			catch ME
				% If problem reading image, display error message
				uialert(app.UIFigure, ME.message, 'Image Error');
				return;
			end
			imagesc(app.ImageAxes,app.im);
		end

	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			% Configure image axes
			app.ImageAxes.Visible = 'off';
			app.ImageAxes.Colormap = gray(256);
			axis(app.ImageAxes, 'image');
		end

		% Button pushed function: GreyscaleButton
		function GreyscaleButtonPushed(app, event)
			[~, ~, numberOfColorChannels] = size(app.im);
			if(numberOfColorChannels>1)
				app.im = rgb2gray(app.im);
				imagesc(app.ImageAxes,app.im);
			elseif(numberOfColorChannels == 1)
				app.im=app.im;
				imagesc(app.ImageAxes,app.im);
			end
		end

		% Button pushed function: BlackAndWhiteButton
		function BlackAndWhiteButtonPushed(app, event)
			[~, ~, numberOfColorChannels] = size(app.im);
			if(numberOfColorChannels==1)
				app.im=imbinarize(app.im);
				imagesc(app.ImageAxes,app.im);
			else
				uialert(app.UIFigure, 'Use greyscale before trying binarisation!','Error');
				return;
			end
		end

		% Button pushed function: UploadYourImageButton
		function UploadYourImageButtonPushed(app, event)
			% Display uigetfile dialog
			filterspec = {'*.jpg;*.png','All Image Files'};
			[file, path] = uigetfile(filterspec);

			% Make sure user didn't cancel uigetfile dialog
			if (ischar(path))
				fname = [path file];
				updateimage(app, fname);
			end
		end

		% Button pushed function: ContrastButton
		function ContrastButtonPushed(app, event)
			if(islogical(app.im))
				uialert(app.UIFigure, 'Cant perform on binarised image','Error');
				return;
			else
				app.im=histeq(app.im);
			end
			imagesc(app.ImageAxes,app.im);
		end

		% Button pushed function: SaveyourImageButton
		function SaveyourImageButtonPushed(app, event)
			startingFolder = userpath;
			defaultFileName = fullfile(startingFolder, '*.*');
			[baseFileName, folder] = uiputfile(defaultFileName, 'Specify a file with an extension');
			if baseFileName == 0
				% User clicked the Cancel button.
				return;
			end
			fullFileName = fullfile(folder, baseFileName);
			imwrite(app.im, fullFileName);
			imwrite(app.I_erosion, fullFileName);
			imwrite(app.I_open, fullFileName);
			imwrite(app.I_close, fullFileName);
		end

		% Button pushed function: CreateahistogramButton
		function CreateahistogramButtonPushed(app, event)
			[~, ~, numberOfColorChannels] = size(app.im);
			if(numberOfColorChannels==1)
				hist = imhist(app.im);
				plot(app.UIAxes, hist);
			else
				uialert(app.UIFigure, 'You can only create histogram of greyscale image!','Error');
				return;
			end
		end

		% Selection changed function: StructuringElementsButtonGroup
		function StructuringElementsButtonGroupSelectionChanged(app, event)
			app.diskse=app.DiamondButton.Value;
			app.diamondse=app.SquareButton_2.Value;
			app.squarese=app.DiskButton_2.Value;
		end

		% Value changed function: SelectSESizeDropDown
		function SelectSESizeDropDownValueChanged(app, event)
			app.sesize=app.SelectSESizeDropDown.Value;
		end

		% Button pushed function: ErosionButton
		function ErosionButtonPushed(app, event)
			if(app.DiamondButton.Value==1)
			se=strel('diamond',app.SelectSESizeDropDown.Value);
			app.I_erosion=imerode(app.im,se);
			imagesc(app.ImageAxes,app.I_erosion);
			elseif(app.SquareButton_2.Value==1)
			se=strel('square',app.SelectSESizeDropDown.Value);
			app.I_erosion=imerode(app.im,se);
			imagesc(app.ImageAxes,app.I_erosion);
			elseif(app.DiskButton_2.Value==1)
			se=strel('disk',app.SelectSESizeDropDown.Value);
			app.I_erosion=imerode(app.im,se);
			imagesc(app.ImageAxes,app.I_erosion);
			end
		end

		% Button pushed function: DilatationButton
		function DilatationButtonPushed(app, event)
			if(app.DiamondButton.Value==1)
			se=strel('diamond',app.SelectSESizeDropDown.Value);
			app.I_dilate=imdilate(app.im,se);
			imagesc(app.ImageAxes,app.I_dilate);
			elseif(app.SquareButton_2.Value==1)
			se=strel('square',app.SelectSESizeDropDown.Value);
			app.I_dilate=imdilate(app.im,se);
			imagesc(app.ImageAxes,app.I_dilate);
			elseif(app.DiskButton_2.Value==1)
			se=strel('disk',app.SelectSESizeDropDown.Value);
			app.I_dilate=imdilate(app.im,se);
			imagesc(app.ImageAxes,app.I_dilate);
			end
		end

		% Button pushed function: OpeningButton
		function OpeningButtonPushed(app, event)
			if(app.DiamondButton.Value==1)
			se=strel('diamond',app.SelectSESizeDropDown.Value);
			app.I_open=imopen(app.im,se);
			imagesc(app.ImageAxes,app.I_open);
			elseif(app.SquareButton_2.Value==1)
			se=strel('square',app.SelectSESizeDropDown.Value);
			app.I_open=imopen(app.im,se);
			imagesc(app.ImageAxes,app.I_open);
			elseif(app.DiskButton_2.Value==1)
			se=strel('disk',app.SelectSESizeDropDown.Value);
			app.I_open=imopen(app.im,se);
			imagesc(app.ImageAxes,app.I_open);
			end
		end

		% Button pushed function: ClosingButton
		function ClosingButtonPushed(app, event)
			if(app.DiamondButton.Value==1)
			se=strel('diamond',app.SelectSESizeDropDown.Value);
			app.I_close=imclose(app.im,se);
			imagesc(app.ImageAxes,app.I_close);
			elseif(app.SquareButton_2.Value==1)
			se=strel('square',app.SelectSESizeDropDown.Value);
			app.I_close=imclose(app.im,se);
			imagesc(app.ImageAxes,app.I_close);
			elseif(app.DiskButton_2.Value==1)
			se=strel('disk',app.SelectSESizeDropDown.Value);
			app.I_close=imclose(app.im,se);
			imagesc(app.ImageAxes,app.I_close);
			end
		end

		% Close request function: UIFigure
		function UIFigureCloseRequest(app, event)
			res = questdlg('Are you sure you want to exit?','EXIT','YES','NO','NO');
            if res == 'YES'
                delete(app);
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
			app.ImageAxes = uiaxes(app.UIFigure);
			app.ImageAxes.XTick = [];
			app.ImageAxes.YTick = [];
			app.UIAxes = uiaxes(app.UIFigure);
			title(app.UIAxes, 'Histogram')
			xlabel(app.UIAxes, 'X')
			ylabel(app.UIAxes, 'Y')
			zlabel(app.UIAxes, 'Z')
			app.GreyscaleButton = uibutton(app.UIFigure, 'push');
			app.GreyscaleButton.ButtonPushedFcn = createCallbackFcn(app, @GreyscaleButtonPushed, true);
			app.GreyscaleButton.Text = 'Greyscale';
			app.BlackAndWhiteButton = uibutton(app.UIFigure, 'push');
			app.BlackAndWhiteButton.ButtonPushedFcn = createCallbackFcn(app, @BlackAndWhiteButtonPushed, true);
			app.BlackAndWhiteButton.Text = 'BlackAndWhite';
			app.UploadYourImageButton = uibutton(app.UIFigure, 'push');
			app.UploadYourImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadYourImageButtonPushed, true);
			app.UploadYourImageButton.Text = 'Upload Your Image';
			app.ContrastButton = uibutton(app.UIFigure, 'push');
			app.ContrastButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastButtonPushed, true);
			app.ContrastButton.Text = {'Contrast'; ''};
			app.SaveyourImageButton = uibutton(app.UIFigure, 'push');
			app.SaveyourImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveyourImageButtonPushed, true);
			app.SaveyourImageButton.Text = 'Save your Image';
			app.CreateahistogramButton = uibutton(app.UIFigure, 'push');
			app.CreateahistogramButton.ButtonPushedFcn = createCallbackFcn(app, @CreateahistogramButtonPushed, true);
			app.CreateahistogramButton.Text = 'Create a histogram';
			app.BasicoperationsLabel = uilabel(app.UIFigure);
			app.BasicoperationsLabel.Text = 'Basic operations';
			app.ImagemorphologyLabel = uilabel(app.UIFigure);
			app.ImagemorphologyLabel.Text = 'Image morphology';
			app.StructuringElementsButtonGroup = uibuttongroup(app.UIFigure);
			app.StructuringElementsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StructuringElementsButtonGroupSelectionChanged, true);
			app.StructuringElementsButtonGroup.Title = 'Structuring Elements';
			app.SquareButton_2 = uiradiobutton(app.StructuringElementsButtonGroup);
			app.SquareButton_2.Text = 'Square';
			app.DiskButton_2 = uiradiobutton(app.StructuringElementsButtonGroup);
			app.DiskButton_2.Text = 'Disk';
			app.DiskButton_2.Value = true;
			app.DiamondButton = uiradiobutton(app.StructuringElementsButtonGroup);
			app.DiamondButton.Text = 'Diamond';
			app.SelectSESizeDropDownLabel = uilabel(app.UIFigure);
			app.SelectSESizeDropDownLabel.Text = 'Select SE Size';
			app.SelectSESizeDropDown = uidropdown(app.UIFigure);
			app.SelectSESizeDropDown.Items = {'3x3', '4x4', '5x5'};
			app.SelectSESizeDropDown.ItemsData = [3 4 5];
			app.SelectSESizeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectSESizeDropDownValueChanged, true);
			app.SelectSESizeDropDown.Value = 3;
			app.ErosionButton = uibutton(app.UIFigure, 'push');
			app.ErosionButton.ButtonPushedFcn = createCallbackFcn(app, @ErosionButtonPushed, true);
			app.ErosionButton.Text = 'Erosion';
			app.DilatationButton = uibutton(app.UIFigure, 'push');
			app.DilatationButton.ButtonPushedFcn = createCallbackFcn(app, @DilatationButtonPushed, true);
			app.DilatationButton.Text = 'Dilatation';
			app.OpeningButton = uibutton(app.UIFigure, 'push');
			app.OpeningButton.ButtonPushedFcn = createCallbackFcn(app, @OpeningButtonPushed, true);
			app.OpeningButton.Text = 'Opening';
			app.ClosingButton = uibutton(app.UIFigure, 'push');
			app.ClosingButton.ButtonPushedFcn = createCallbackFcn(app, @ClosingButtonPushed, true);
			app.ClosingButton.Text = 'Closing';
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = app1

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			% Execute the startup function
			runStartupFcn(app, @startupFcn)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end